classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        InstructionLabel         matlab.ui.control.Label
        ButtonPanel              matlab.ui.container.Panel
        RightArrowImage          matlab.ui.control.Image
        AddVanishingPointButton  matlab.ui.control.Button
        AddInnerRectangleButton  matlab.ui.control.Button
        AddPolygonButton         matlab.ui.control.Button
        DoneButton               matlab.ui.control.Button
        BackButton               matlab.ui.control.Button
        ImagePanel               matlab.ui.container.Panel
        AddImageButton           matlab.ui.control.Button
        ImageBox                 matlab.ui.control.UIAxes
        ScreenNameLabel          matlab.ui.control.Label
        TourIntoThePictureLabel  matlab.ui.control.Label
    end

    
    properties (Access = private)
        OriginImage % Image to process
        ForegroundImages = {} % Foreground of the Image
        BackgroundImage % Background of the Image
        Screen % Store the state of the app
        RoisForeground = {} % Region of interest for foreground objects
        NumRoiForeground = 0 % Index of region of interest
        OuterRectangle % Rectangle made from the outer corner points of the image
        InnerRectangle % Inner rectangle
        RoiInnerRectangle % Region of interest inner rectangle
        RoiVanishingPoint % Region of interest for the vanishing point
        VanishingPoint % Vanishing point
        TwelfPoints % 12 points for contruction 3D model from the picture
        RoiOrthogonalLine = cell(1,4) % 4 Line connecting the vanishing point and 4 corners of inner rectangle
        PaddedImage % Padded image
    end

    methods (Access = private)

        function SetScreen(app)
            switch app.Screen
                case Screens.AddImage
                    app.ActivateAddImageScreen()
                case Screens.ChooseForeground
                    app.ActivateChooseForegroundScreen()
                case Screens.SeparateWallBackground
                    app.ActivateSeparateWallsBackgroundScreen()
            end

        end

        %% Screen Activation Functions

        function ActivateAddImageScreen(app)
            cla(app.ImageBox);
            app.NumRoiForeground = 0;
            app.ScreenNameLabel.Text = 'Add Image';
            app.InstructionLabel.Text = 'Add an image by clicking';
            app.AddImageButton.Visible = "on";
            app.BackButton.Visible = "off";
            app.AddPolygonButton.Visible = "off";
            app.DoneButton.Visible = "off";
            app.AddInnerRectangleButton.Visible = "off";
            app.AddVanishingPointButton.Visible = "off";
            app.RightArrowImage.Visible = "off";
        end

        function ActivateChooseForegroundScreen(app)
            app.NumRoiForeground = 0;
            cla(app.ImageBox)
            imshow(app.OriginImage, 'Parent', app.ImageBox)            
            app.ScreenNameLabel.Text = 'Select foreground objects';
            app.InstructionLabel.Text = ['Select the foreground objects in the image ' ...
                'by drawing a region of interest around each one of them'];
            app.AddImageButton.Visible = "off";
            app.BackButton.Visible = "on";
            app.BackButton.Tooltip = "Back to add image";
            app.DoneButton.Visible = "on";
            app.AddPolygonButton.Visible = "on";
            app.RightArrowImage.Visible = "off";
            app.UIFigure.Pointer = "arrow";
            app.AddInnerRectangleButton.Visible = "off";
            app.AddVanishingPointButton.Visible = "off";
        end

        function ActivateSeparateWallsBackgroundScreen(app)
            app.ScreenNameLabel.Text = 'Separate walls';
            app.InstructionLabel.Text = ['Draw the inner rectangle and ' ...
                'vanishing point to define the walls'];
            app.AddImageButton.Visible = "off";
            app.BackButton.Visible = "on";
            app.BackButton.Tooltip = "Back to choose foreground";
            app.DoneButton.Visible = "off";
            app.AddPolygonButton.Visible = "off";
            app.AddInnerRectangleButton.Visible = "on";
            app.AddInnerRectangleButton.Enable = "on";
            app.AddVanishingPointButton.Visible = "on";
            app.AddVanishingPointButton.Enable = "off";
            app.RightArrowImage.Visible = "on";
        end

        %% Listeners Callbacks
        function UpdateSpideryMesh(app, src, ~)
            for i = 1:4
                if ~isempty(app.RoiOrthogonalLine{i})
                    delete(app.RoiOrthogonalLine{i})
                end
            end

            if src == app.RoiVanishingPoint || src == "initial"
                app.VanishingPoint = app.RoiVanishingPoint.Position';
            end

            if src == app.RoiInnerRectangle || src == "initial"
                % Assign new corners to inner rectangle
                % Note: The coordinate system is: right handed, y
                % downwards, x to the right, z into the plane
                xBottomLeft = app.RoiInnerRectangle.Position(1); % x coordinate of bottom left corner
                yBottomLeft = app.RoiInnerRectangle.Position(2); % y coordinate of bottom left corner

                IR_Width  = app.RoiInnerRectangle.Position(3); % Width of the inner rectangle
                IR_Height = app.RoiInnerRectangle.Position(4); % Height of the inner rectangle

                app.InnerRectangle = [
                    [xBottomLeft; yBottomLeft], ...                        % Top-left corner
                    [xBottomLeft + IR_Width; yBottomLeft], ...             % Top-right corner
                    [xBottomLeft + IR_Width; yBottomLeft + IR_Height], ... % Bottom-right corner
                    [xBottomLeft; yBottomLeft + IR_Height], ...            % Bottom-left corner                    
                    ];
            end
            
            % Get 12 points according to Horry et al.
            % (http://graphics.cs.cmu.edu/courses/15-463/2006_fall/www/Papers/TIP.pdf)
            app.TwelfPoints = get12Points(app.VanishingPoint, app.InnerRectangle, app.OuterRectangle);

            % Find 4 of those points which lie on the outer rectangle
            twelfPoints = app.TwelfPoints;
            fourPoints = zeros(2,4);
            points_counter = 0;
            for i = 1:12
                if any(i == [1 2 7 8])
                    continue
                end

                if isPointOnRectangleEdge(twelfPoints(:,i), app.OuterRectangle(:,1), app.OuterRectangle(:,3),1e-5)
                    points_counter = points_counter + 1;
                    fourPoints(:,points_counter) = twelfPoints(:,i);                    
                end
      
                if points_counter == 4
                    break
                end
            end

            % Draw 4 orthogonal lines from the outer rectangle to the
            % vanishing point
            for i = 1:4
                app.RoiOrthogonalLine{i} = line(app.ImageBox, ...
                    [app.VanishingPoint(1) fourPoints(1,i)], [app.VanishingPoint(2) fourPoints(2,i)], ...
                    'Color', "#0072BD", 'LineWidth', 2);
                bringToFront(app.RoiInnerRectangle)
                bringToFront(app.RoiVanishingPoint)
            end           
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)
            app.Screen = Screens.AddImage;
            addpath("helper_functions\");
        end

        % Button pushed function: AddImageButton
        function AddImageButtonPushed(app, event)
            
            % So that user can see the pop-up window for choosing image 
            app.UIFigure.WindowStyle = "normal";

            [file, location, indx] = uigetfile({'*.jpg;*.png;*.bmp', 'Images (*.jpg, *.png, *.bmp)'});

            app.UIFigure.WindowStyle = "alwaysontop";

            if isequal(file,0)
                disp('User selected Cancel')
                return
            else
                disp(['User selected ', fullfile(location,file),...
                    ' and filter index: ', num2str(indx)])
            end

            app.OriginImage = imread(fullfile(location, file));

            [sz1, sz2] = size(app.OriginImage);

            % Image size reduction, faster image processing
            if any(sz1>3000 | sz2>3000)
                reduce_scal = 3200/max(sz1,sz2);
                app.OriginImage = imresize(app.OriginImage, reduce_scal,"Antialiasing",false);
            end    

            % Get coordinate of outer rectangle a.k.a 4 corners of the image
            ImageSizeX = size(app.OriginImage,2);
            ImageSizeY = size(app.OriginImage,1);
            app.OuterRectangle = [
                [0; 0], ...                   % Top-left corner
                [ImageSizeX; 0], ...          % Top-right corner
                [ImageSizeX; ImageSizeY], ... % Bottom-right corner
                [0; ImageSizeY] ...           % Bottom-left corner
                ];

            app.Screen = Screens.ChooseForeground;
            app.SetScreen();
        end

        % Button pushed function: AddPolygonButton
        function AddPolygonButtonButtonPushed(app, event)
            % app.UIFigure.Pointer = "crosshair";
            app.ImageBox.Clipping = "on";
            app.DoneButton.Enable = "off";
            app.NumRoiForeground = app.NumRoiForeground + 1;
            app.RoisForeground{app.NumRoiForeground} = drawpolygon(app.ImageBox);            
            if isempty(app.RoisForeground{app.NumRoiForeground}.Position)
                app.NumRoiForeground = app.NumRoiForeground - 1;
            end
            app.ImageBox.Clipping = "off";
            app.DoneButton.Enable = "on";

        end

        % Button pushed function: AddInnerRectangleButton
        function AddInnerRectangleButtonButtonPushed(app, event)
            app.ImageBox.Clipping = "on";
            app.RoiInnerRectangle = drawrectangle(app.ImageBox);
            if ~isempty(app.RoiInnerRectangle.Position)
                app.AddInnerRectangleButton.Enable = "off";
                app.AddVanishingPointButton.Enable = "on";
            end

            app.ImageBox.Clipping = "off";
            
        end

        % Button pushed function: AddVanishingPointButton
        function AddVanishingPointButtonButtonPushed(app, event)
            app.ImageBox.Clipping = "on";
            app.RoiVanishingPoint = drawpoint(app.ImageBox);
            if ~isempty(app.RoiVanishingPoint.Position)
                app.AddInnerRectangleButton.Enable = "off";
                app.AddVanishingPointButton.Enable = "off";
                app.DoneButton.Visible = "on";
            end
            app.ImageBox.Clipping = "off";   
            UpdateSpideryMesh(app,"initial")


            % Update the position of the Vanishing Point     
            addlistener(app.RoiVanishingPoint, 'MovingROI', @app.UpdateSpideryMesh);
            addlistener(app.RoiInnerRectangle, 'MovingROI', @app.UpdateSpideryMesh);


        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if app.Screen == Screens.ChooseForeground % Choosing foreground finished
                app.AddPolygonButton.Visible = "off";
                app.DoneButton.Visible = "off";
    
                app.BackgroundImage = app.OriginImage;
                app.ForegroundImages = cell(1,app.NumRoiForeground);
    
                % Parameters for function inpaintExemplar
                patchsize = 9;
                fillorder = 'gradient';
    
                app.UIFigure.WindowStyle = "normal";
                app.BackButton.Enable = 0;
                d = waitbar(0.2,'Rendering image ...'); % Open up waitbar
                disableDefaultInteractivity(app.ImageBox)
    
                for i = 1:app.NumRoiForeground
                    % separate the foreground mask and app.Background picture
                    foregroundmask = createMask(app.RoisForeground{i});
                    app.ForegroundImages{i} = bsxfun(@times,app.OriginImage, cast(foregroundmask, 'like', app.OriginImage));
                    app.BackgroundImage = inpaintExemplar(app.BackgroundImage, foregroundmask, "PatchSize", patchsize, "FillOrder", fillorder);
                    waitbar(i/app.NumRoiForeground,d,'Rendering image ...');
                end
    
                close(d) % Close waitbar
    
                app.UIFigure.WindowStyle = "alwaysontop";
                enableDefaultInteractivity(app.ImageBox)
                app.BackButton.Enable = 1;
    
                cla(app.ImageBox)
                imshow(app.BackgroundImage, 'Parent', app.ImageBox)
    
                % Screen transistion
                app.Screen = Screens(app.Screen + 1);
                app.SetScreen();

            elseif app.Screen == Screens.SeparateWallBackground % Separating wall finished
                [app.PaddedImage, topPadding, leftPadding] = padImage(app.TwelfPoints, app.BackgroundImage);
                cla(app.ImageBox)
                imshow(app.PaddedImage,'Parent', app.ImageBox)

                % Update position of the 12 points, vanishing point, inner and outer rectangle after padding
                app.TwelfPoints = app.TwelfPoints + [leftPadding; topPadding]; 
                app.InnerRectangle = app.InnerRectangle + [leftPadding; topPadding];
                app.OuterRectangle = app.OuterRectangle + [leftPadding; topPadding];
                app.VanishingPoint = app.VanishingPoint + [leftPadding; topPadding];

                drawWallsToBackground(app.ImageBox, app.VanishingPoint, app.TwelfPoints, app.InnerRectangle, app.OuterRectangle)
                
            end

        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.Screen = Screens(app.Screen - 1);
            app.SetScreen();
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TourIntoThePictureLabel = uilabel(app.UIFigure);
            app.TourIntoThePictureLabel.Text = 'Tour Into The Picture';
            app.ScreenNameLabel = uilabel(app.UIFigure);
            app.ScreenNameLabel.Text = 'Add picture ';
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImageBox = uiaxes(app.ImagePanel);
            app.ImageBox.XTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ImageBox.XTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.ImageBox.YTick = [0 0.1 0.2 0.3 0.4 0.5 0.6 0.7 0.8 0.9 1];
            app.ImageBox.YTickLabel = {'0'; '0.1'; '0.2'; '0.3'; '0.4'; '0.5'; '0.6'; '0.7'; '0.8'; '0.9'; '1'};
            app.ImageBox.Visible = 'off';
            app.AddImageButton = uibutton(app.ImagePanel, 'push');
            app.AddImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImageButton.Icon = fullfile(pathToMLAPP, 'icons', 'SelectPicture.png');
            app.AddImageButton.Tooltip = {'Add image'};
            app.AddImageButton.Text = '';
            app.ButtonPanel = uipanel(app.UIFigure);
            app.BackButton = uibutton(app.ButtonPanel, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = fullfile(pathToMLAPP, 'icons', 'Back.png');
            app.BackButton.Visible = 'off';
            app.BackButton.Tooltip = {'Back'};
            app.BackButton.Text = '';
            app.DoneButton = uibutton(app.ButtonPanel, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Icon = fullfile(pathToMLAPP, 'icons', 'Done.png');
            app.DoneButton.Visible = 'off';
            app.DoneButton.Tooltip = {'Finished'};
            app.DoneButton.Text = '';
            app.AddPolygonButton = uibutton(app.ButtonPanel, 'push');
            app.AddPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @AddPolygonButtonButtonPushed, true);
            app.AddPolygonButton.Icon = fullfile(pathToMLAPP, 'icons', 'AddPolygon.png');
            app.AddPolygonButton.Visible = 'off';
            app.AddPolygonButton.Tooltip = {'Add polygon'};
            app.AddPolygonButton.Text = '';
            app.AddInnerRectangleButton = uibutton(app.ButtonPanel, 'push');
            app.AddInnerRectangleButton.ButtonPushedFcn = createCallbackFcn(app, @AddInnerRectangleButtonButtonPushed, true);
            app.AddInnerRectangleButton.Icon = fullfile(pathToMLAPP, 'icons', 'DrawRectangle.png');
            app.AddInnerRectangleButton.Visible = 'off';
            app.AddInnerRectangleButton.Tooltip = {'Draw rectangle'};
            app.AddInnerRectangleButton.Text = '';
            app.AddVanishingPointButton = uibutton(app.ButtonPanel, 'push');
            app.AddVanishingPointButton.ButtonPushedFcn = createCallbackFcn(app, @AddVanishingPointButtonButtonPushed, true);
            app.AddVanishingPointButton.Icon = fullfile(pathToMLAPP, 'icons', 'VanishingPoint.png');
            app.AddVanishingPointButton.Enable = 'off';
            app.AddVanishingPointButton.Visible = 'off';
            app.AddVanishingPointButton.Tooltip = {'Add vanishing point(s)'};
            app.AddVanishingPointButton.Text = '';
            app.RightArrowImage = uiimage(app.ButtonPanel);
            app.RightArrowImage.Visible = 'off';
            app.RightArrowImage.ImageSource = fullfile(pathToMLAPP, 'icons', 'RightArrow.png');
            app.InstructionLabel = uilabel(app.UIFigure);
            app.InstructionLabel.Text = 'Add an image by clicking';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end