classdef GUI < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                 matlab.ui.Figure
        InstructionLabel         matlab.ui.control.Label
        ButtonPanel              matlab.ui.container.Panel
        ButtonGridLayout         matlab.ui.container.GridLayout
        SliderPanel              matlab.ui.container.Panel
        SliderGridLayout         matlab.ui.container.GridLayout
        Slider                   matlab.ui.control.Slider
        RightArrowImage          matlab.ui.control.Image
        AddVanishingPointButton  matlab.ui.control.Button
        AddInnerRectangleButton  matlab.ui.control.Button
        ShowAnimationButton      matlab.ui.control.Button
        Contruct3DButton         matlab.ui.control.Button
        AddPolygonButton         matlab.ui.control.Button
        DoneButton               matlab.ui.control.Button
        BackButton               matlab.ui.control.Button
        ImagePanel               matlab.ui.container.Panel
        ImagePanelGridLayout     matlab.ui.container.GridLayout
        AddImageButton           matlab.ui.control.Button
        ImagePreviewAxes         matlab.ui.control.UIAxes
        ScreenNameLabel          matlab.ui.control.Label
        TourIntoThePictureLabel  matlab.ui.control.Label
    end


    properties (Access = private)
        OriginImage % Image to process
        RotatedImage % Rotated image
        ForegroundImages = {} % Foreground of the Image
        BackgroundImage % Background of the Image
        Screen % Store the state of the app
        RoisForeground2D = {} % Region of interest for foreground objects
        Foreground2D = {} % Foreground points in 2D
        Foreground3D = {} % Foreground points in 3D
        NumRoiForeground = 0 % Index of region of interest
        OuterRectangle % Rectangle made from the outer corner points of the image
        InnerRectangle % Inner rectangle
        RoiInnerRectangle % Region of interest inner rectangle
        RoiVanishingPoint % Region of interest for the vanishing point
        VanishingPoint % Vanishing point
        TwelfPoints2D = zeros(2,12) % 12 points in 2D for contruction of 3D model from the picture
        UpdatedTwelfPoints2D = zeros(2,12) % Updated 12 points after padding the image
        TwelfPoints3D = zeros(3,12) % 12 points in 3D for contruction of 3D model from the picture
        RoiOrthogonalLine = cell(1,4) % 4 Line connecting the vanishing point and 4 corners of inner rectangle
        PaddedImage % Padded image
    end

    methods (Access = private)

        function SetScreen(app)
            switch app.Screen
                case Screens.AddImage
                    app.ActivateAddImageScreen()
                case Screens.RotateImage
                    app.ActivateRotateImageScreen()
                case Screens.ChooseForeground
                    app.ActivateChooseForegroundScreen()
                case Screens.SeparateWallBackground
                    app.ActivateSeparateWallsBackgroundScreen()
                case Screens.ViewRoomAndAnimation
                    app.ActivateViewRoomAndAnimationScreen()
            end

        end

        %% Screen Activation Functions

        function ActivateAddImageScreen(app)
            cla(app.ImagePreviewAxes);
            app.ScreenNameLabel.Text = 'Add Image';
            app.InstructionLabel.Text = 'Add an image by clicking';
            app.AddImageButton.Visible = "on";
            app.BackButton.Visible = "off";
            app.AddPolygonButton.Visible = "off";
            app.DoneButton.Visible = "off";
            app.AddInnerRectangleButton.Visible = "off";
            app.AddVanishingPointButton.Visible = "off";
            app.RightArrowImage.Visible = "off";
            app.SliderPanel.Visible = "off";
        end

        function ActivateRotateImageScreen(app)
            app.NumRoiForeground = 0;
            app.RotatedImage = app.OriginImage;
            imshow(app.RotatedImage, 'Parent', app.ImagePreviewAxes)
            app.ScreenNameLabel.Text = 'Rotate Image';
            app.InstructionLabel.Text = 'Rotate the image using the slider';
            app.AddImageButton.Visible = "off";
            app.BackButton.Visible = "on";
            app.BackButton.Tooltip = "Back to add another image";
            app.AddPolygonButton.Visible = "off";
            app.DoneButton.Visible = "on";
            app.AddInnerRectangleButton.Visible = "off";
            app.AddVanishingPointButton.Visible = "off";
            app.RightArrowImage.Visible = "off";
            app.SliderPanel.Visible = "on";
        end

        function ActivateChooseForegroundScreen(app)
            app.NumRoiForeground = 0;
            cla(app.ImagePreviewAxes)
            imshow(app.RotatedImage, 'Parent', app.ImagePreviewAxes)
            app.ScreenNameLabel.Text = 'Select foreground objects';
            app.InstructionLabel.Text = ['Select the foreground objects in the image ' ...
                'by drawing a region of interest around each one of them'];
            app.AddImageButton.Visible = "off";
            app.BackButton.Visible = "on";
            app.BackButton.Tooltip = "Back to rotate the image";
            app.DoneButton.Visible = "on";
            app.AddPolygonButton.Visible = "on";
            app.RightArrowImage.Visible = "off";
            app.UIFigure.Pointer = "arrow";
            app.AddInnerRectangleButton.Visible = "off";
            app.AddVanishingPointButton.Visible = "off";
            app.SliderPanel.Visible = "off";
        end

        function ActivateSeparateWallsBackgroundScreen(app)
            cla(app.ImagePreviewAxes)
            imshow(app.BackgroundImage, 'Parent', app.ImagePreviewAxes)
            app.ScreenNameLabel.Text = 'Separate walls';
            app.InstructionLabel.Text = ['Draw the inner rectangle and ' ...
                'vanishing point to define the walls'];
            app.BackButton.Tooltip = "Back to choose foreground again";
            app.DoneButton.Visible = "off";
            app.AddPolygonButton.Visible = "off";
            app.AddInnerRectangleButton.Visible = "on";
            app.AddInnerRectangleButton.Enable = "on";
            app.AddVanishingPointButton.Visible = "on";
            app.AddVanishingPointButton.Enable = "off";
            app.RightArrowImage.Visible = "on";
            app.Contruct3DButton.Visible = "off";
            app.ShowAnimationButton.Visible = "off";
        end

        function ActivateViewRoomAndAnimationScreen(app)
            app.ScreenNameLabel.Text = '3D Room and Animation';
            app.InstructionLabel.Text = ['You can now view the 3D room as ' ...
                'well as watch the animation tour into the picture'];
            app.BackButton.Tooltip = "Back to separate the walls again";
            app.DoneButton.Visible = "off";
            app.AddPolygonButton.Visible = "off";
            app.AddInnerRectangleButton.Visible = "off";
            app.AddInnerRectangleButton.Enable = "off";
            app.AddVanishingPointButton.Visible = "off";
            app.Contruct3DButton.Visible = "on";
            app.ShowAnimationButton.Visible = "on";
            app.ShowAnimationButton.Enable = "off";
        end

        %% Listeners Callbacks
        function UpdateSpideryMesh(app, src, ~)
            for i = 1:4
                if ~isempty(app.RoiOrthogonalLine{i})
                    delete(app.RoiOrthogonalLine{i})
                end
            end

            if src == app.RoiVanishingPoint || src == "initial"
                app.VanishingPoint = app.RoiVanishingPoint.Position';
            end

            if src == app.RoiInnerRectangle || src == "initial"
                % Assign new corners to inner rectangle
                % Note: The coordinate system is: right handed, y
                % downwards, x to the right, z into the plane
                xBottomLeft = app.RoiInnerRectangle.Position(1); % x coordinate of bottom left corner
                yBottomLeft = app.RoiInnerRectangle.Position(2); % y coordinate of bottom left corner

                IR_Width  = app.RoiInnerRectangle.Position(3); % Width of the inner rectangle
                IR_Height = app.RoiInnerRectangle.Position(4); % Height of the inner rectangle

                app.InnerRectangle = [
                    [xBottomLeft; yBottomLeft], ...                        % Top-left corner
                    [xBottomLeft + IR_Width; yBottomLeft], ...             % Top-right corner
                    [xBottomLeft + IR_Width; yBottomLeft + IR_Height], ... % Bottom-right corner
                    [xBottomLeft; yBottomLeft + IR_Height], ...            % Bottom-left corner
                    ];
            end

            % Get 12 points according to Horry et al.
            % (http://graphics.cs.cmu.edu/courses/15-463/2006_fall/www/Papers/TIP.pdf)
            app.TwelfPoints2D = get12Points(app.VanishingPoint, app.InnerRectangle, app.OuterRectangle);

            % Find 4 of those points which lie on the outer rectangle
            twelfPoints = app.TwelfPoints2D;
            fourPoints = zeros(2,4);
            points_counter = 0;
            for i = 1:12
                if any(i == [1 2 7 8])
                    continue
                end

                if isPointOnRectangleEdge(twelfPoints(:,i), app.OuterRectangle(:,1), app.OuterRectangle(:,3),1e-5)
                    points_counter = points_counter + 1;
                    fourPoints(:,points_counter) = twelfPoints(:,i);
                end

                if points_counter == 4
                    break
                end
            end

            % Draw 4 orthogonal lines from the outer rectangle to the
            % vanishing point
            for i = 1:4
                app.RoiOrthogonalLine{i} = line(app.ImagePreviewAxes, ...
                    [app.VanishingPoint(1) fourPoints(1,i)], [app.VanishingPoint(2) fourPoints(2,i)], ...
                    'Color', "#0072BD", 'LineWidth', 2);
                bringToFront(app.RoiInnerRectangle)
                bringToFront(app.RoiVanishingPoint)
            end
        end
    end

    % Callbacks that handle component events
    methods (Access = private)

        % Code that executes after component creation
        function startupFcn(app)

            % Make sure the app runs in the folder containing it
            fullPath = mfilename('fullpath');
            fileDir = fileparts(fullPath);
            cd(fileDir);

            app.Screen = Screens.AddImage;
            addpath("helper_functions\");

        end

        % Button pushed function: AddImageButton
        function AddImageButtonPushed(app, event)

            % So that user can see the pop-up window for choosing image
            app.UIFigure.WindowStyle = "normal";

            cd ../images/
            [file, location, indx] = uigetfile({'*.jpg;*.png;*.bmp', 'Images (*.jpg, *.png, *.bmp)'});
            cd ../src/
            app.UIFigure.WindowStyle = "alwaysontop";

            if isequal(file,0)
                disp('User selected Cancel')
                return
            else
                disp(['User selected ', fullfile(location,file),...
                    ' and filter index: ', num2str(indx)])
            end

            app.OriginImage = imread(fullfile(location, file));

            [sz1, sz2] = size(app.OriginImage);

            % Image size reduction, faster image processing
            if any(sz1>3000 | sz2>3000)
                reduce_scal = 3200/max(sz1,sz2);
                app.OriginImage = imresize(app.OriginImage, reduce_scal,"Antialiasing",false);
            end

            % Get coordinate of outer rectangle a.k.a 4 corners of the image
            ImageSizeX = size(app.OriginImage,2);
            ImageSizeY = size(app.OriginImage,1);
            app.OuterRectangle = [
                [0; 0], ...                   % Top-left corner
                [ImageSizeX; 0], ...          % Top-right corner
                [ImageSizeX; ImageSizeY], ... % Bottom-right corner
                [0; ImageSizeY] ...           % Bottom-left corner
                ];

            app.Screen = Screens(app.Screen + 1);
            app.SetScreen();
        end

        % Button pushed function: AddPolygonButton
        function AddPolygonButtonButtonPushed(app, event)

            app.ImagePreviewAxes.Clipping = "on";
            app.DoneButton.Enable = "off";
            app.NumRoiForeground = app.NumRoiForeground + 1;
            app.RoisForeground2D{app.NumRoiForeground} = drawpolygon(app.ImagePreviewAxes);
            if isempty(app.RoisForeground2D{app.NumRoiForeground}.Position)
                app.NumRoiForeground = app.NumRoiForeground - 1;
            end
            app.ImagePreviewAxes.Clipping = "off";
            app.DoneButton.Enable = "on";

        end

        % Button pushed function: AddInnerRectangleButton
        function AddInnerRectangleButtonButtonPushed(app, event)
            app.ImagePreviewAxes.Clipping = "on";
            app.RoiInnerRectangle = drawrectangle(app.ImagePreviewAxes);
            if ~isempty(app.RoiInnerRectangle.Position)
                app.AddInnerRectangleButton.Enable = "off";
                app.AddVanishingPointButton.Enable = "on";
            end

            app.ImagePreviewAxes.Clipping = "off";

        end

        % Button pushed function: AddVanishingPointButton
        function AddVanishingPointButtonButtonPushed(app, event)
            app.ImagePreviewAxes.Clipping = "on";
            app.RoiVanishingPoint = drawpoint(app.ImagePreviewAxes);
            if ~isempty(app.RoiVanishingPoint.Position)
                app.AddInnerRectangleButton.Enable = "off";
                app.AddVanishingPointButton.Enable = "off";
                app.DoneButton.Visible = "on";
            end
            app.ImagePreviewAxes.Clipping = "off";
            UpdateSpideryMesh(app,"initial")


            % Update the position of the Vanishing Point
            addlistener(app.RoiVanishingPoint, 'MovingROI', @app.UpdateSpideryMesh);
            addlistener(app.RoiInnerRectangle, 'MovingROI', @app.UpdateSpideryMesh);
            
        end

        % Button pushed function: Contruct3DButton
        function Contruct3DButtonPushed(app, event)

            IR_height = app.InnerRectangle(2,4) - app.InnerRectangle(2,1);
            IR_width = app.InnerRectangle(1,2) - app.InnerRectangle(1,1);

            app.TwelfPoints3D = get12Points3D(app.UpdatedTwelfPoints2D, app.VanishingPoint, IR_height, IR_width);
            app.Foreground3D = getForeground3D(app.Foreground2D, app.TwelfPoints2D, app.TwelfPoints3D, app.VanishingPoint);
            out_size = [1000, 1000];
            textureBackground = getTextureBackground(app.PaddedImage, app.UpdatedTwelfPoints2D, out_size);
            textureForeground = getTextureForeground(app.OriginImage, app.Foreground2D, out_size); % TODO
            ax = gca;
            contructRoom(textureForeground, textureBackground, app.Foreground3D, app.TwelfPoints3D, ax)     
            app.ShowAnimationButton.Enable = "on";
        end

        % Button pushed function: ShowAnimationButton
        function ShowAnimationButtonPushed(app, event)
            ax = gca;
            startAnimation(ax,[app.VanishingPoint; 0],app.TwelfPoints3D); 
        end

        % Button pushed function: DoneButton
        function DoneButtonPushed(app, event)
            if app.Screen == Screens.ChooseForeground % Choosing foreground finished
                app.AddPolygonButton.Visible = "off";
                app.DoneButton.Visible = "off";

                app.BackgroundImage = app.RotatedImage;
                app.ForegroundImages = cell(1,app.NumRoiForeground);

                % Parameters for function inpaintExemplar
                patchsize = 9;
                fillorder = 'gradient';

                app.UIFigure.WindowStyle = "normal";
                app.BackButton.Enable = 0;
                d = waitbar(0.2,'Rendering image ...'); % Open up waitbar
                disableDefaultInteractivity(app.ImagePreviewAxes)

                for i = 1:app.NumRoiForeground
                    app.Foreground2D{i} = app.RoisForeground2D{i}.Position';
                    disp(app.Foreground2D{i})
                    % separate the foreground mask and app.Background picture
                    foregroundmask = createMask(app.RoisForeground2D{i});
                    app.ForegroundImages{i} = bsxfun(@times,app.RotatedImage, cast(foregroundmask, 'like', app.RotatedImage));
                    app.BackgroundImage = inpaintExemplar(app.BackgroundImage, foregroundmask, "PatchSize", patchsize, "FillOrder", fillorder);
                    waitbar(i/app.NumRoiForeground,d,'Rendering image ...');
                end

                close(d) % Close waitbar

                app.UIFigure.WindowStyle = "alwaysontop";
                enableDefaultInteractivity(app.ImagePreviewAxes)
                app.BackButton.Enable = "on";
                app.DoneButton.Visible = "on";
                app.Contruct3DButton.Visible = "off";
                app.ShowAnimationButton.Visible = "off";
                app.RightArrowImage.Visible = "on";

                cla(app.ImagePreviewAxes)
                imshow(app.BackgroundImage, 'Parent', app.ImagePreviewAxes) 

            elseif app.Screen == Screens.SeparateWallBackground % Separating wall finished
                [app.PaddedImage, topPadding, leftPadding] = padImage(app.TwelfPoints2D, app.BackgroundImage);
                cla(app.ImagePreviewAxes)
                imshow(app.PaddedImage,'Parent', app.ImagePreviewAxes)

                % Update position of the 12 points, vanishing point, inner and outer rectangle after padding
                app.UpdatedTwelfPoints2D = app.TwelfPoints2D + [leftPadding; topPadding];
                updatedInnerRectangle = app.InnerRectangle + [leftPadding; topPadding];
                app.VanishingPoint = app.VanishingPoint + [leftPadding; topPadding];

                drawWallsToBackground(app.ImagePreviewAxes, app.VanishingPoint, app.UpdatedTwelfPoints2D, updatedInnerRectangle)
            end

            app.Screen = Screens(app.Screen + 1);
            app.SetScreen();


        end

        % Button pushed function: BackButton
        function BackButtonPushed(app, event)
            app.Screen = Screens(app.Screen - 1);
            app.SetScreen();
        end

        % Value changing function: Slider
        function SliderValueChanging(app, event)
            angle = event.Value;
            app.RotatedImage = imrotate(app.OriginImage, angle, 'crop');
            imshow(app.RotatedImage, 'Parent', app.ImagePreviewAxes)
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            % Get the file path for locating images
            pathToMLAPP = fileparts(mfilename('fullpath'));
            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.TourIntoThePictureLabel = uilabel(app.UIFigure);
            app.TourIntoThePictureLabel.Text = 'Tour Into The Picture';
            app.ScreenNameLabel = uilabel(app.UIFigure);
            app.ScreenNameLabel.Text = 'Add picture ';
            app.ImagePanel = uipanel(app.UIFigure);
            app.ImagePanelGridLayout = uigridlayout(app.ImagePanel);
            app.ImagePreviewAxes = uiaxes(app.ImagePanelGridLayout);
            app.ImagePreviewAxes.Visible = 'off';
            app.AddImageButton = uibutton(app.ImagePanelGridLayout, 'push');
            app.AddImageButton.ButtonPushedFcn = createCallbackFcn(app, @AddImageButtonPushed, true);
            app.AddImageButton.Icon = fullfile(pathToMLAPP, '..', 'icons', 'SelectPicture.png');
            app.AddImageButton.Tooltip = {'Add image'};
            app.AddImageButton.Text = '';
            app.ButtonPanel = uipanel(app.UIFigure);
            app.ButtonGridLayout = uigridlayout(app.ButtonPanel);
            app.BackButton = uibutton(app.ButtonGridLayout, 'push');
            app.BackButton.ButtonPushedFcn = createCallbackFcn(app, @BackButtonPushed, true);
            app.BackButton.Icon = fullfile(pathToMLAPP, '..', 'icons', 'Back.png');
            app.BackButton.Visible = 'off';
            app.BackButton.Tooltip = {'Back'};
            app.BackButton.Text = '';
            app.DoneButton = uibutton(app.ButtonGridLayout, 'push');
            app.DoneButton.ButtonPushedFcn = createCallbackFcn(app, @DoneButtonPushed, true);
            app.DoneButton.Icon = fullfile(pathToMLAPP, '..', 'icons', 'Done.png');
            app.DoneButton.Visible = 'off';
            app.DoneButton.Tooltip = {'Finished'};
            app.DoneButton.Text = '';
            app.AddPolygonButton = uibutton(app.ButtonGridLayout, 'push');
            app.AddPolygonButton.ButtonPushedFcn = createCallbackFcn(app, @AddPolygonButtonButtonPushed, true);
            app.AddPolygonButton.Icon = fullfile(pathToMLAPP, '..', 'icons', 'AddPolygon.png');
            app.AddPolygonButton.Visible = 'off';
            app.AddPolygonButton.Tooltip = {'Add polygon'};
            app.AddPolygonButton.Text = '';
            app.Contruct3DButton = uibutton(app.ButtonGridLayout, 'push');
            app.Contruct3DButton.ButtonPushedFcn = createCallbackFcn(app, @Contruct3DButtonPushed, true);
            app.Contruct3DButton.Icon = fullfile(pathToMLAPP, '..', 'icons', '3D.png');
            app.Contruct3DButton.Visible = 'off';
            app.Contruct3DButton.Tooltip = {'Contruct 3D box from image'};
            app.Contruct3DButton.Text = '';
            app.ShowAnimationButton = uibutton(app.ButtonGridLayout, 'push');
            app.ShowAnimationButton.ButtonPushedFcn = createCallbackFcn(app, @ShowAnimationButtonPushed, true);
            app.ShowAnimationButton.Icon = fullfile(pathToMLAPP, '..', 'icons', 'Animation.png');
            app.ShowAnimationButton.Visible = 'off';
            app.ShowAnimationButton.Tooltip = {'Show animation'};
            app.ShowAnimationButton.Text = '';
            app.AddInnerRectangleButton = uibutton(app.ButtonGridLayout, 'push');
            app.AddInnerRectangleButton.ButtonPushedFcn = createCallbackFcn(app, @AddInnerRectangleButtonButtonPushed, true);
            app.AddInnerRectangleButton.Icon = fullfile(pathToMLAPP, '..', 'icons', 'DrawRectangle.png');
            app.AddInnerRectangleButton.Visible = 'off';
            app.AddInnerRectangleButton.Tooltip = {'Draw rectangle'};
            app.AddInnerRectangleButton.Text = '';
            app.AddVanishingPointButton = uibutton(app.ButtonGridLayout, 'push');
            app.AddVanishingPointButton.ButtonPushedFcn = createCallbackFcn(app, @AddVanishingPointButtonButtonPushed, true);
            app.AddVanishingPointButton.Icon = fullfile(pathToMLAPP, '..', 'icons', 'VanishingPoint.png');
            app.AddVanishingPointButton.Enable = 'off';
            app.AddVanishingPointButton.Visible = 'off';
            app.AddVanishingPointButton.Tooltip = {'Add vanishing point(s)'};
            app.AddVanishingPointButton.Text = '';
            app.RightArrowImage = uiimage(app.ButtonGridLayout);
            app.RightArrowImage.Visible = 'off';
            app.RightArrowImage.ImageSource = fullfile(pathToMLAPP, '..', 'icons', 'RightArrow.png');
            app.SliderPanel = uipanel(app.ButtonGridLayout);
            app.SliderPanel.Visible = 'off';
            app.SliderGridLayout = uigridlayout(app.SliderPanel);
            app.Slider = uislider(app.SliderGridLayout);
            app.Slider.Limits = [-90 90];
            app.Slider.MajorTicks = [-90 0 90];
            app.Slider.MajorTickLabels = {'-90°', '0°', '90°'};
            app.Slider.ValueChangingFcn = createCallbackFcn(app, @SliderValueChanging, true);
            app.InstructionLabel = uilabel(app.UIFigure);
            app.InstructionLabel.Text = 'Add an image by clicking';
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = GUI

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            % Execute the startup function
            runStartupFcn(app, @startupFcn)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end